classdef EWGODs_Main_Control_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EWGameofDronesUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PlanningTab                    matlab.ui.container.Tab
        PreviewPanel                   matlab.ui.container.Panel
        UIAxesPreview                  matlab.ui.control.UIAxes
        SensorListPanel                matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        SensorsListBoxLabel            matlab.ui.control.Label
        SensorsListBox                 matlab.ui.control.ListBox
        RemoveButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        AddSensorPanel                 matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        IdEditField                    matlab.ui.control.NumericEditField
        IdEditFieldLabel               matlab.ui.control.Label
        YPositionEditField             matlab.ui.control.NumericEditField
        YPositionEditFieldLabel        matlab.ui.control.Label
        XPositionEditField             matlab.ui.control.NumericEditField
        XPositionEditFieldLabel        matlab.ui.control.Label
        OrientationDropDown            matlab.ui.control.DropDown
        OrientationDropDownLabel       matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        AddSensorButton                matlab.ui.control.Button
        BattlfieldTab                  matlab.ui.container.Tab
        ThreatDetectedBtn              matlab.ui.control.Button
        DroneImg_2                     matlab.ui.control.Image
        DroneImg                       matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        DroneImg_7                     matlab.ui.control.Image
        DroneImg_6                     matlab.ui.control.Image
        DroneImg_5                     matlab.ui.control.Image
        DroneImg_4                     matlab.ui.control.Image
        DroneImg_3                     matlab.ui.control.Image
        ThreatsDetectedEditField       matlab.ui.control.NumericEditField
        ThreatsDetectedEditFieldLabel  matlab.ui.control.Label
        DroneImg_8                     matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        CommandCenterPanel             matlab.ui.container.Panel
        SystemControlPanel             matlab.ui.container.Panel
        PortDropDown                   matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        STARTButton                    matlab.ui.control.StateButton
        SystemTimeLabel                matlab.ui.control.Label
        SystemTimeEditField            matlab.ui.control.EditField
        SyncButton                     matlab.ui.control.Button
        GunControlPanel                matlab.ui.container.Panel
        YawNegButton                   matlab.ui.control.Button
        YawPlusButton                  matlab.ui.control.Button
        PitchNegativeButton            matlab.ui.control.Button
        PitchPositiveButton            matlab.ui.control.Button
        FIREButton                     matlab.ui.control.Button
        ARMSwitch                      matlab.ui.control.ToggleSwitch
        ARMSwitchLabel                 matlab.ui.control.Label
        DataDashboardPanel             matlab.ui.container.Panel
        UltrasonicSensorsPanel_1       matlab.ui.container.Panel
        UltrasonicSensorsGridLayout_1  matlab.ui.container.GridLayout
        UltrasonicSensor_Distance_EditField_3  matlab.ui.control.EditField
        UltrasonicSensor_Distance_EditField_2  matlab.ui.control.EditField
        UltrasonicSensor_Distance_EditField_1  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_3  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_2  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_1  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_3  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_2  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_1  matlab.ui.control.EditField
        UltrasonicSensor_ID_Label_1    matlab.ui.control.Label
        UltrasonicSensor_TIME_Label_1  matlab.ui.control.Label
        UltrasonicSensor_Distance_Label_1  matlab.ui.control.Label
        LidarSensorsPanel              matlab.ui.container.Panel
        UltrasonicSensorsGridLayout_2  matlab.ui.container.GridLayout
        UltrasonicSensor_Distance_EditField_6  matlab.ui.control.EditField
        UltrasonicSensor_Distance_EditField_5  matlab.ui.control.EditField
        UltrasonicSensor_Distance_EditField_4  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_6  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_5  matlab.ui.control.EditField
        UltrasonicSensor_Time_EditField_4  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_6  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_5  matlab.ui.control.EditField
        UltrasonicSensor_Id_EditField_4  matlab.ui.control.EditField
        UltrasonicSensor_ID_Label      matlab.ui.control.Label
        UltrasonicSensor_TIME_Label    matlab.ui.control.Label
        UltrasonicSensor_Distance_Label  matlab.ui.control.Label
        SSRT_Panel                     matlab.ui.container.Panel
        SSRT_GridLayout                matlab.ui.container.GridLayout
        SSRT_Id_EditField_2            matlab.ui.control.EditField
        SSRT_Time_EditField_2          matlab.ui.control.EditField
        SSRT_Rssi_EditField_2          matlab.ui.control.EditField
        SSRT_Id_EditField_1            matlab.ui.control.EditField
        SSRT_TIME_Label                matlab.ui.control.Label
        SSRT_Time_EditField_1          matlab.ui.control.EditField
        SSRT_RSSI_Label                matlab.ui.control.Label
        SSRT_Rssi_EditField_1          matlab.ui.control.EditField
        SSRT_ID_Label                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        zigB %software defined radio hardware/comms protocol (serialport)
        sensorList %Sensors that will get plotted
        gameState %control flow of application
        run = true;
    end
    
    properties (Access = private)
        ids % Description
        currentSensorSelected % current sensor selected in Item List
        %sensorList %Sensors that will get plotted
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
%             try
%                 app.zigB = initArduino;
%             catch
%             end
%             try
%                 syncTime(app);
%             catch
%             end
%             try
%                 readFromXbee(app);
%             catch
%             end

            %NEW CODE TEST
            try
                gameManager(app);
            catch
            end
            
            %{
            WORKING CODE BELOW WHILE EDITING
            app.PortDropDown.Items = serialportlist("available");
            
            
            app.gameState = 'planning';
            while true
                try
                switch app.gameState
                    case 'planning'
                        %disp("Planning...");
                        try
                            app.zigB = initArduino;
                        catch
                        end
                        try
                            syncTime(app);
                        catch
                        end
                        %disp(app.zigB);
                        %pause(100);
                    case 'running'
                        
                        try
                            readFromXbee(app);
                        catch
                        end
                        
                    case 'quiting'
                        %disp("Game is over quiting now....");
                        if app.STARTButton.Value == 1
                            app.gameState = 'planning';
                        end
                end
                catch
                    break;
                end
               
            end
            %}

        end

        % Button pushed function: SyncButton
        function SyncButtonPushed(app, event)
            syncTime(app);
        end

        % Value changed function: ARMSwitch
        function ARMSwitchValueChanged(app, event)
            armMissile(app);
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            fireMissile(app);
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            disp("START button defaul VALUE");
            disp(app.STARTButton.Value);
            value = app.STARTButton.Value;
            
            
            %disp("helloooooooo")
            %instrreset;
            %out = instrfindall;
            %out = serialportlist("all");
            %app.PortDropDown.L
            %display(out);
            %disp(instrfind); 
            if value == 1
                disp("GAME has started");
                
                %app.gameState = 'running';
                
                set(app.ThreatsDetectedEditField,'visible','on');
                set(app.ThreatsDetectedEditFieldLabel, 'visible', 'on');
                set(app.ThreatDetectedBtn, 'visible', 'on');
                app.ThreatsDetectedEditField.Value = 0;
                app.STARTButton.BackgroundColor = [1 0 0];
                app.STARTButton.Text = "END";
                
            elseif value == 0
                disp("Game has concluded");
                disp(app.STARTButton.Value);
                %app.gameState = 'quiting';
                %app.run = false;
                
                app.STARTButton.BackgroundColor = [0.39 0.83 0.07];
                app.STARTButton.Text = "START";
                
                set(app.ThreatsDetectedEditField,'visible','off')
                set(app.ThreatsDetectedEditFieldLabel, 'visible', 'off');
                set(app.ThreatDetectedBtn, 'visible', 'off');
            end
        end

        % Button pushed function: YawNegButton
        function YawNegButtonPushed(app, event)
            yawNeg(app);
        end

        % Button pushed function: YawPlusButton
        function YawPlusButtonPushed(app, event)
            yawPlus(app);
        end

        % Button pushed function: PitchPositiveButton
        function PitchPositiveButtonPushed(app, event)
            pitchPositive(app);
        end

        % Button pushed function: PitchNegativeButton
        function PitchNegativeButtonPushed(app, event)
            pitchNegative(app);
        end

        % Button pushed function: AddSensorButton
        function AddSensorButtonPushed(app, event)
            % used to hold the temp sensor listbox
            names = {};

            % Edit Fields that are required to add a sensor
            id = num2str(app.IdEditField.Value);
            type = app.TypeDropDown.Value;
            orientation = app.OrientationDropDown.Value;
            xPos = num2str(app.XPositionEditField.Value);
            yPos = num2str(app.YPositionEditField.Value);
            
            % Check if SensorListBox is empty
            get(app.SensorsListBox);
            
            % convert edit field to a double for storing
            tempId = str2double(id);
            
            % convert sensor edit fields into a string
            name = strcat(id,",",type,",",orientation,',',xPos, ', ', yPos);
            % first check if that id is already taken
            if ismember(tempId, app.ids)
                % if id is taken send out error msg
                disp("Error that ID is already being used.");
                errordlg('Error that ID is already being used.','Error');
            else
                %% id is available 
                
                % add it to the id list
                app.ids = [app.ids; tempId];
                
                % create temp sensor object
                sensorObj = sensor(id, type, orientation, xPos, yPos);
                
                % add sensor to global list
                app.sensorList = [app.sensorList; sensorObj];
                
                % add sensor string to local list
                names = [names name];
                
                % copy SensorListBox Items List to temp holder
                temp = app.SensorsListBox.Items;
            
                % update temp holder with current sensor names
                temp = [temp names];

                % Update SensorListBox Items
                app.SensorsListBox.Items = temp;
            end
            %disp('app.ids');
            %disp(app.ids);
            %disp('app.sensorList');
%             for i = 1:length(app.sensorList)
%                 disp(app.sensorList(i))
%             end
            %disp(app.sensorList);
            %disp('app.sensorList ', app.sensorList);
            %% Not using class I set up
            %tempObj = sensor(id, type, orientation, xPos, yPos);
            %tempObj.disp();
            
            %disp(app.SensorsListBox.Items);
            
            %IDEAS for proposal
            %Main Idea
            %virtual 3d environment aka 3d detection display
            
            %dynamic mini-iads aka sensor components on servos
          
            %ar env for blue side
            %virtual vs. ar environments
            
            %automate one side for more player vs. ai

            
%             ls = app.SensorsListBox.Items;
%             ls = [id, type;];
%             
%             app.SensorsListBox.Items = ls;
            %%app.SensorsListBox.ItemsData = listboxItems;
            % Check if SensorListBox is empty
            
            % After adding
            %len = length(app.SensorsListBox.Items);
            %disp("Items Length: " + len);
            %disp(app.SensorsListBox.Items);
            %get(app.SensorsListBox);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            %% FIX needed here 4 when clicking remove and nothing is selected....
            % Error using cell/ismember (line 34)
            % Input A of class double and input B of class cell must be cell arrays of character vectors, unless one is a character vector.
            try
            if ismember(app.currentSensorSelected, app.SensorsListBox.Items)
                [~,idx] = ismember(app.currentSensorSelected, app.SensorsListBox.Items);
                %disp("true index is: " + idx);
                %disp("ID list @ index " + idx + " is " + app.ids(idx) );
                app.ids(idx) = [];
                app.SensorsListBox.Items(idx) = [];
                app.sensorList(idx) = [];
                %disp(idx);
            
            end
            catch
            end
            
            
        end

        % Value changed function: SensorsListBox
        function SensorsListBoxValueChanged(app, event)
            app.currentSensorSelected = app.SensorsListBox.Value;
            %disp(app.currentSensorSelected);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % clear plot
            cla(app.UIAxesPreview);
            cla(app.UIAxes);
            % variables used
            % ultrasonics
            ultrasonicXCoordsNorth = [];
            ultrasonicYCoordsNorth = [];
            ultrasonicXCoordsSouth = [];
            ultrasonicYCoordsSouth = [];
            ultrasonicXCoordsWest = [];
            ultrasonicYCoordsWest = [];
            ultrasonicXCoordsEast = [];
            ultrasonicYCoordsEast = [];
            % lidars
            lidarXCoordsNorth = [];
            lidarYCoordsNorth = [];
            lidarXCoordsSouth = [];
            lidarYCoordsSouth = [];
            lidarXCoordsWest = [];
            lidarYCoordsWest = [];
            lidarXCoordsEast = [];
            lidarYCoordsEast = [];
            %rfids
            rfidXCoordsNorth = [];
            rfidYCoordsNorth = [];
            rfidXCoordsSouth = [];
            rfidYCoordsSouth = [];
            rfidXCoordsWest = [];
            rfidYCoordsWest = [];
            rfidXCoordsEast = [];
            rfidYCoordsEast = [];
            % deadly effectors
            deadlyXCoordsNorth = [];
            deadlyYCoordsNorth = [];
            deadlyXCoordsSouth = [];
            deadlyYCoordsSouth = [];
            deadlyXCoordsWest = [];
            deadlyYCoordsWest = [];
            deadlyXCoordsEast = [];
            deadlyYCoordsEast = [];
            
            %process sensor list
            for i = 1:length(app.sensorList)
                %disp('type: ')
                %disp(app.sensorList(i).Type);
                % add ultrasonic coords
                if strcmp(app.sensorList(i).Type, 'Ultrasonic')
                    if strcmp(app.sensorList(i).Orientation, 'North')
                        ultrasonicXCoordsNorth(end + 1) = str2double(app.sensorList(i).XPos);
                        ultrasonicYCoordsNorth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'South')
                        ultrasonicXCoordsSouth(end + 1) = str2double(app.sensorList(i).XPos);
                        ultrasonicYCoordsSouth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'West')
                        ultrasonicXCoordsWest(end + 1) = str2double(app.sensorList(i).XPos);
                        ultrasonicYCoordsWest(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'East')
                        ultrasonicXCoordsEast(end + 1) = str2double(app.sensorList(i).XPos);
                        ultrasonicYCoordsEast(end + 1) = str2double(app.sensorList(i).YPos);    
                    end
                    
                % add lidar coords
                elseif strcmp(app.sensorList(i).Type, 'Lidar')
                    if strcmp(app.sensorList(i).Orientation, 'North')
                        lidarXCoordsNorth(end + 1) = str2double(app.sensorList(i).XPos);
                        lidarYCoordsNorth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'South')
                        lidarXCoordsSouth(end + 1) = str2double(app.sensorList(i).XPos);
                        lidarYCoordsSouth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'West')
                        lidarXCoordsWest(end + 1) = str2double(app.sensorList(i).XPos);
                        lidarYCoordsWest(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'East')
                        lidarXCoordsEast(end + 1) = str2double(app.sensorList(i).XPos);
                        lidarYCoordsEast(end + 1) = str2double(app.sensorList(i).YPos);    
                    end

                % add rfid coords
                elseif strcmp(app.sensorList(i).Type, 'RFID')
                    if strcmp(app.sensorList(i).Orientation, 'North')
                        rfidXCoordsNorth(end + 1) = str2double(app.sensorList(i).XPos);
                        rfidYCoordsNorth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'South')
                        rfidXCoordsSouth(end + 1) = str2double(app.sensorList(i).XPos);
                        rfidYCoordsSouth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'West')
                        rfidXCoordsWest(end + 1) = str2double(app.sensorList(i).XPos);
                        rfidYCoordsWest(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'East')
                        rfidXCoordsEast(end + 1) = str2double(app.sensorList(i).XPos);
                        rfidYCoordsEast(end + 1) = str2double(app.sensorList(i).YPos);    
                    end
                % deadly effectors
                elseif strcmp(app.sensorList(i).Type, 'Deadly Effector')
                    if strcmp(app.sensorList(i).Orientation, 'North')
                        deadlyXCoordsNorth(end + 1) = str2double(app.sensorList(i).XPos);
                        deadlyYCoordsNorth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'South')
                        deadlyXCoordsSouth(end + 1) = str2double(app.sensorList(i).XPos);
                        deadlyYCoordsSouth(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'West')
                        deadlyXCoordsWest(end + 1) = str2double(app.sensorList(i).XPos);
                        deadlyYCoordsWest(end + 1) = str2double(app.sensorList(i).YPos);
                    elseif strcmp(app.sensorList(i).Orientation, 'East')
                        deadlyXCoordsEast(end + 1) = str2double(app.sensorList(i).XPos);
                        deadlyYCoordsEast(end + 1) = str2double(app.sensorList(i).YPos);    
                    end
                    
                end
            end
            %% Load UIAxesPreview
            % plot ultrasonic coordinates
            plot(app.UIAxesPreview, ultrasonicXCoordsNorth, ultrasonicYCoordsNorth, 'Marker', 'v', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, ultrasonicXCoordsSouth, ultrasonicYCoordsSouth, 'Marker', '^', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, ultrasonicXCoordsWest, ultrasonicYCoordsWest, 'Marker', '>', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, ultrasonicXCoordsEast, ultrasonicYCoordsEast, 'Marker', '<', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            % plot lidar coordinates
            plot(app.UIAxesPreview, lidarXCoordsNorth,lidarYCoordsNorth,'Marker','v','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, lidarXCoordsSouth,lidarYCoordsSouth,'Marker','^','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, lidarXCoordsWest,lidarYCoordsWest,'Marker','>','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, lidarXCoordsEast,lidarYCoordsEast,'Marker','<','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            % plot rfid coordinates
            plot(app.UIAxesPreview, rfidXCoordsNorth,rfidYCoordsNorth,'Marker','v','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, rfidXCoordsSouth,rfidYCoordsSouth,'Marker','^','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, rfidXCoordsWest,rfidYCoordsWest,'Marker','>','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            plot(app.UIAxesPreview, rfidXCoordsEast,rfidYCoordsEast,'Marker','<','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview,"on");
            % plot deadly effectors
            %Killzone
            plot(app.UIAxesPreview, deadlyXCoordsNorth,deadlyYCoordsNorth, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxesPreview, 'on');
            %Nerf Gun positon
            plot(app.UIAxesPreview, deadlyXCoordsNorth,deadlyYCoordsNorth, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview, 'on');
          
            plot(app.UIAxesPreview, deadlyXCoordsSouth,deadlyYCoordsSouth, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxesPreview, 'on');
            %Nerf Gun positon
            plot(app.UIAxesPreview, deadlyXCoordsSouth,deadlyYCoordsSouth, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview, 'on');
            
            plot(app.UIAxesPreview, deadlyXCoordsWest,deadlyYCoordsWest, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxesPreview, 'on');
            %Nerf Gun positon
            plot(app.UIAxesPreview, deadlyXCoordsWest,deadlyYCoordsWest, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview, 'on');
            
            plot(app.UIAxesPreview, deadlyXCoordsEast,deadlyYCoordsEast, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxesPreview, 'on');
            %Nerf Gun positon
            plot(app.UIAxesPreview, deadlyXCoordsEast,deadlyYCoordsEast, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxesPreview, 'on');
            
            %% Load UIAxes
            % plot ultrasonic coordinates
            plot(app.UIAxes, ultrasonicXCoordsNorth, ultrasonicYCoordsNorth, 'Marker', 'v', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, ultrasonicXCoordsSouth, ultrasonicYCoordsSouth, 'Marker', '^', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, ultrasonicXCoordsWest, ultrasonicYCoordsWest, 'Marker', '>', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, ultrasonicXCoordsEast, ultrasonicYCoordsEast, 'Marker', '<', 'MarkerSize', 15, 'Color', 'r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            % plot lidar coordinates
            plot(app.UIAxes, lidarXCoordsNorth,lidarYCoordsNorth,'Marker','v','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, lidarXCoordsSouth,lidarYCoordsSouth,'Marker','^','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, lidarXCoordsWest,lidarYCoordsWest,'Marker','>','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, lidarXCoordsEast,lidarYCoordsEast,'Marker','<','MarkerSize', 15,'Color',[0.4660 0.6740 0.1880],'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            % plot rfid coordinates
            plot(app.UIAxes, rfidXCoordsNorth,rfidYCoordsNorth,'Marker','v','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, rfidXCoordsSouth,rfidYCoordsSouth,'Marker','^','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, rfidXCoordsWest,rfidYCoordsWest,'Marker','>','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            plot(app.UIAxes, rfidXCoordsEast,rfidYCoordsEast,'Marker','<','MarkerSize', 15,'Color','b','LineWidth',1,'LineStyle','none');
            hold(app.UIAxes,"on");
            % plot deadly effectors
            %Killzone
            plot(app.UIAxes, deadlyXCoordsNorth,deadlyYCoordsNorth, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxes, 'on');
            %Nerf Gun positon
            plot(app.UIAxes, deadlyXCoordsNorth,deadlyYCoordsNorth, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes, 'on');
          
            plot(app.UIAxes, deadlyXCoordsSouth,deadlyYCoordsSouth, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxes, 'on');
            %Nerf Gun positon
            plot(app.UIAxes, deadlyXCoordsSouth,deadlyYCoordsSouth, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes, 'on');
            
            plot(app.UIAxes, deadlyXCoordsWest,deadlyYCoordsWest, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxes, 'on');
            %Nerf Gun positon
            plot(app.UIAxes, deadlyXCoordsWest,deadlyYCoordsWest, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes, 'on');
            
            plot(app.UIAxes, deadlyXCoordsEast,deadlyYCoordsEast, 'bo', 'MarkerSize', 75,'Color','r','LineWidth',1,'LineStyle','--');
            hold(app.UIAxes, 'on');
            %Nerf Gun positon
            plot(app.UIAxes, deadlyXCoordsEast,deadlyYCoordsEast, 'Marker','x','MarkerSize', 15, 'Color','r', 'LineWidth',1,'LineStyle','none');
            hold(app.UIAxes, 'on');
            
            %Quadrants X
            y_quad = 0:26;
            x1 = 6;
            x2 = 12;
            x3 = 18;
            plot(app.UIAxes,x1*ones(size(y_quad)), y_quad, 'Color','r', 'LineWidth',5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x2*ones(size(y_quad)), y_quad, 'Color','r', 'LineWidth',5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x3*ones(size(y_quad)), y_quad, 'Color','r', 'LineWidth',5);
            hold(app.UIAxes, 'on');
            
             %Quadrants Y
            x_quad = 0:25;
            y1 = 9.5;
            y2 = 18.4;
            plot(app.UIAxes,x_quad, y1*ones(size(x_quad)), 'Color','r', 'LineWidth',5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,x_quad, y2*ones(size(x_quad)), 'Color','r', 'LineWidth',5);
            hold(app.UIAxes, 'on');


            %disp("UI AXES");
            %disp(get(app.UIAxes.Children));
            %for i = 1:length(get(app.UIAxes.Children))
            %    disp(get(app.UIAxes.Children(i)));
            %end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Want to save what and where? How?
            C = {};
            
            for i = 1:length(app.sensorList)
                C{i,1} = app.sensorList(i).Id;
                C{i,2} = app.sensorList(i).Type;
                C{i,3} = app.sensorList(i).Orientation;
                C{i,4} = app.sensorList(i).XPos;
                C{i,5} = app.sensorList(i).YPos;
                
            
            end
            
            t = table(cell2table(C,...
                'VariableNames',{'Id' 'Type' 'Orientation' 'XPos' 'YPos'}));
            t2 = splitvars(t);
            
            %disp(t2);
            % Copied
            startingFolder = "saves/"; % Or "c:\mydata" or wherever you want.
            defaultFileName = fullfile(startingFolder, '*.csv');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
            if baseFileName == 0
            	% User clicked the Cancel button.
            	return;
            end
            % Get base file name, so we can ignore whatever extension they may have typed in.
            [~, baseFileNameNoExt, ~] = fileparts(baseFileName);
            fullFileName = fullfile(folder, [baseFileNameNoExt, '.csv']);    % Force an extension of .xlsx.
            writetable(t2,fullFileName);
            % end copied
            
            %type 'saves/test.csv';
            %writetable(t2,'saves/test.xlsx');
            %writetable(t2,'saves/test.csv');
            %type 'saves/test.csv';
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% clear data before?
            %clear sensorList/ids/
            app.ids = [];
            app.sensorList = [];
            app.SensorsListBox.Items = {};
            %% How to load a .csv
            [file,path,indx] = uigetfile('saves/test.csv',...
                        'Select an CSV File');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)]);
                %disp("Path is ");
                %disp(path);
                %disp("file is ");
                %disp(file);
                
            end
            %might need a try catch here
            folder = 'saves/';
            actualfile = strcat(folder,file);
            T = readtable(actualfile);
            %disp(T);
            %% need function to load Table into GUI
            %% put in app.ids and app.sensorList
            %A = T{1:T.Properties.length,{'Id', 'Type', 'Orientation', 'XPos', 'YPos'}};
            
            %used to hold/seperate the data that was just read in
            IdColumn = T.Id;
            TypeColumn = T.Type;
            OrientationColumn = T.Orientation;
            XPosColumn = T.XPos;
            YPosColumn = T.YPos;
            
            % used to hold the temp sensor listbox
            names = {};
            
            % copy SensorListBox Items List to temp holder
            temp = app.SensorsListBox.Items;
            %disp(height(T));
            
            for i = 1:height(T)
                %disp(i);
               
                
                %% set up to transfer data
                tempId = num2str(IdColumn(i));
                tempType = TypeColumn(i);
                tempOrientation = OrientationColumn(i);
                tempXPos = num2str(XPosColumn(i));
                tempYPos = num2str(YPosColumn(i));
                
                % concat sensor data into a string
                name = strcat(tempId,",",tempType,",",tempOrientation,',',tempXPos, ', ', tempYPos);
                
                % create temp sensor object
                sensorObj = sensor(tempId, tempType, tempOrientation, tempXPos, tempYPos);
                
                 %% set up app.ids
                % convert edit field to a double for storing
                actualId = str2double(tempId);
                
                % add it to the id list
                app.ids = [app.ids; actualId];
                %disp("app.ids");
                %disp(app.ids);

                % add sensor to global list
                app.sensorList = [app.sensorList; sensorObj];

                % add sensor string to local list
                names = [names name];
                %disp("names");
                %disp(names);

            end
            
            %h = height(T);
            
            
            % convert sensor edit fields into a string
            %name = strcat(id,",",type,",",orientation,',',xPos, ', ', yPos);
            
            % convert edit field to a double for storing
            %tempId = str2double(id);
            
            % add it to the id list
            %app.ids = [app.ids; tempId];

            % create temp sensor object
            %sensorObj = sensor(id, type, orientation, xPos, yPos);

            % add sensor to global list
            %app.sensorList = [app.sensorList; sensorObj];

            % add sensor string to local list
            %names = [names name];

            % copy SensorListBox Items List to temp holder
            %temp = app.SensorsListBox.Items;

            % update temp holder with current sensor names
            %temp = [temp names];

            % Update SensorListBox Items
            app.SensorsListBox.Items = names;
            %disp("app.SLB.Items");
            %disp(app.SensorsListBox.Items);
        end

        % Button pushed function: ThreatDetectedBtn
        function ThreatDetectedBtnPushed(app, event)
            app.ThreatsDetectedEditField.Value = app.ThreatsDetectedEditField.Value + 1;
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            %value = app.PortDropDown.Value;
            %disp("Drop down changed to: ");
            %disp(value);
%             try
%                 app.zigB = initArduino(app);
%                 disp("zigB started...");
%             catch
%             end
%             try
%                 readFromXbee(app);
%             catch
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EWGameofDronesUIFigure = uifigure('Visible', 'off');
            app.EWGameofDronesUIFigure.Name = 'EW Game of Drones';
            app.DataDashboardPanel = uipanel(app.EWGameofDronesUIFigure);
            app.DataDashboardPanel.Title = 'Data Dashboard';
            app.SSRT_Panel = uipanel(app.DataDashboardPanel);
            app.SSRT_Panel.Title = 'SSRT';
            app.SSRT_GridLayout = uigridlayout(app.SSRT_Panel);
            app.SSRT_ID_Label = uilabel(app.SSRT_GridLayout);
            app.SSRT_ID_Label.Text = 'ID';
            app.SSRT_Rssi_EditField_1 = uieditfield(app.SSRT_GridLayout, 'text');
            app.SSRT_RSSI_Label = uilabel(app.SSRT_GridLayout);
            app.SSRT_RSSI_Label.Text = 'RSSI';
            app.SSRT_Time_EditField_1 = uieditfield(app.SSRT_GridLayout, 'text');
            app.SSRT_TIME_Label = uilabel(app.SSRT_GridLayout);
            app.SSRT_TIME_Label.Text = 'Time';
            app.SSRT_Id_EditField_1 = uieditfield(app.SSRT_GridLayout, 'text');
            app.SSRT_Rssi_EditField_2 = uieditfield(app.SSRT_GridLayout, 'text');
            app.SSRT_Time_EditField_2 = uieditfield(app.SSRT_GridLayout, 'text');
            app.SSRT_Id_EditField_2 = uieditfield(app.SSRT_GridLayout, 'text');
            app.LidarSensorsPanel = uipanel(app.DataDashboardPanel);
            app.LidarSensorsPanel.Title = 'Lidar Sensors';
            app.UltrasonicSensorsGridLayout_2 = uigridlayout(app.LidarSensorsPanel);
            app.UltrasonicSensor_Distance_Label = uilabel(app.UltrasonicSensorsGridLayout_2);
            app.UltrasonicSensor_Distance_Label.Text = 'Distance (cm)';
            app.UltrasonicSensor_TIME_Label = uilabel(app.UltrasonicSensorsGridLayout_2);
            app.UltrasonicSensor_TIME_Label.Text = 'Time';
            app.UltrasonicSensor_ID_Label = uilabel(app.UltrasonicSensorsGridLayout_2);
            app.UltrasonicSensor_ID_Label.Text = 'ID';
            app.UltrasonicSensor_Id_EditField_4 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Id_EditField_5 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Id_EditField_6 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Time_EditField_4 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Time_EditField_5 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Time_EditField_6 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Distance_EditField_4 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Distance_EditField_5 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensor_Distance_EditField_6 = uieditfield(app.UltrasonicSensorsGridLayout_2, 'text');
            app.UltrasonicSensorsPanel_1 = uipanel(app.DataDashboardPanel);
            app.UltrasonicSensorsPanel_1.Title = 'Ultrasonic Sensors';
            app.UltrasonicSensorsGridLayout_1 = uigridlayout(app.UltrasonicSensorsPanel_1);
            app.UltrasonicSensor_Distance_Label_1 = uilabel(app.UltrasonicSensorsGridLayout_1);
            app.UltrasonicSensor_Distance_Label_1.Text = 'Distance (cm)';
            app.UltrasonicSensor_TIME_Label_1 = uilabel(app.UltrasonicSensorsGridLayout_1);
            app.UltrasonicSensor_TIME_Label_1.Text = 'Time';
            app.UltrasonicSensor_ID_Label_1 = uilabel(app.UltrasonicSensorsGridLayout_1);
            app.UltrasonicSensor_ID_Label_1.Text = 'ID';
            app.UltrasonicSensor_Id_EditField_1 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Id_EditField_2 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Id_EditField_3 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Time_EditField_1 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Time_EditField_2 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Time_EditField_3 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Distance_EditField_1 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Distance_EditField_2 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.UltrasonicSensor_Distance_EditField_3 = uieditfield(app.UltrasonicSensorsGridLayout_1, 'text');
            app.CommandCenterPanel = uipanel(app.EWGameofDronesUIFigure);
            app.CommandCenterPanel.Title = 'Command Center';
            app.ARMSwitchLabel = uilabel(app.CommandCenterPanel);
            app.ARMSwitchLabel.Text = 'ARM';
            app.ARMSwitch = uiswitch(app.CommandCenterPanel, 'toggle');
            app.ARMSwitch.ValueChangedFcn = createCallbackFcn(app, @ARMSwitchValueChanged, true);
            app.FIREButton = uibutton(app.CommandCenterPanel, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE';
            app.GunControlPanel = uipanel(app.CommandCenterPanel);
            app.GunControlPanel.Title = 'Gun Control';
            app.PitchPositiveButton = uibutton(app.GunControlPanel, 'push');
            app.PitchPositiveButton.ButtonPushedFcn = createCallbackFcn(app, @PitchPositiveButtonPushed, true);
            app.PitchPositiveButton.Text = '+ Pitch';
            app.PitchNegativeButton = uibutton(app.GunControlPanel, 'push');
            app.PitchNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @PitchNegativeButtonPushed, true);
            app.PitchNegativeButton.Text = '- Pitch';
            app.YawPlusButton = uibutton(app.GunControlPanel, 'push');
            app.YawPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YawPlusButtonPushed, true);
            app.YawPlusButton.Text = '+ Yaw';
            app.YawNegButton = uibutton(app.GunControlPanel, 'push');
            app.YawNegButton.ButtonPushedFcn = createCallbackFcn(app, @YawNegButtonPushed, true);
            app.YawNegButton.Text = '- Yaw';
            app.SystemControlPanel = uipanel(app.CommandCenterPanel);
            app.SystemControlPanel.Title = 'System Control';
            app.SyncButton = uibutton(app.SystemControlPanel, 'push');
            app.SyncButton.ButtonPushedFcn = createCallbackFcn(app, @SyncButtonPushed, true);
            app.SyncButton.Text = 'Sync';
            app.SystemTimeEditField = uieditfield(app.SystemControlPanel, 'text');
            app.SystemTimeLabel = uilabel(app.SystemControlPanel);
            app.SystemTimeLabel.Text = 'System Time';
            app.STARTButton = uibutton(app.SystemControlPanel, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.PortDropDownLabel = uilabel(app.SystemControlPanel);
            app.PortDropDownLabel.Text = 'Port #';
            app.PortDropDown = uidropdown(app.SystemControlPanel);
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.EWGameofDronesUIFigure);
            app.PlanningTab = uitab(app.TabGroup);
            app.PlanningTab.Title = 'Planning';
            app.AddSensorPanel = uipanel(app.PlanningTab);
            app.AddSensorPanel.Title = 'Add Sensor';
            app.GridLayout2 = uigridlayout(app.AddSensorPanel);
            app.AddSensorButton = uibutton(app.GridLayout2, 'push');
            app.AddSensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensorButtonPushed, true);
            app.AddSensorButton.Text = 'Add Sensor';
            app.TypeDropDownLabel = uilabel(app.GridLayout2);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout2);
            app.TypeDropDown.Items = {'Ultrasonic', 'RFID', 'Lidar', 'Deadly Effector', ''};
            app.TypeDropDown.Value = 'Ultrasonic';
            app.OrientationDropDownLabel = uilabel(app.GridLayout2);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.GridLayout2);
            app.OrientationDropDown.Items = {'North', 'South', 'West', 'East'};
            app.OrientationDropDown.Value = 'North';
            app.XPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XPositionEditField.Limits = [0 20];
            app.YPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YPositionEditField.Limits = [0 26];
            app.IdEditFieldLabel = uilabel(app.GridLayout2);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IdEditField.Limits = [1 100];
            app.IdEditField.Value = 1;
            app.SensorListPanel = uipanel(app.PlanningTab);
            app.SensorListPanel.Title = 'Sensor List';
            app.GridLayout = uigridlayout(app.SensorListPanel);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RemoveButton = uibutton(app.GridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SensorsListBox = uilistbox(app.GridLayout);
            app.SensorsListBox.Items = {};
            app.SensorsListBox.ValueChangedFcn = createCallbackFcn(app, @SensorsListBoxValueChanged, true);
            app.SensorsListBox.Value = {};
            app.SensorsListBoxLabel = uilabel(app.GridLayout);
            app.SensorsListBoxLabel.Text = 'Sensors';
            app.PreviewPanel = uipanel(app.PlanningTab);
            app.PreviewPanel.Title = 'Preview Panel';
            app.UIAxesPreview = uiaxes(app.PreviewPanel);
            title(app.UIAxesPreview, 'Preview')
            xlabel(app.UIAxesPreview, 'X')
            ylabel(app.UIAxesPreview, 'Y')
            zlabel(app.UIAxesPreview, 'Z')
            app.UIAxesPreview.XLim = [0 20];
            app.UIAxesPreview.YLim = [0 26];
            app.UIAxesPreview.XTick = [0 5 10 15 20];
            app.UIAxesPreview.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxesPreview.YTick = [0 5 10 15 20 25];
            app.UIAxesPreview.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.BattlfieldTab = uitab(app.TabGroup);
            app.BattlfieldTab.Title = 'Battlfield';
            app.UIAxes = uiaxes(app.BattlfieldTab);
            xlabel(app.UIAxes, 'X (ft)')
            ylabel(app.UIAxes, 'Y (ft)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 26];
            app.UIAxes.XTick = [0 5 10 15 20];
            app.DroneImg_8 = uiimage(app.BattlfieldTab);
            app.DroneImg_8.Visible = 'off';
            app.DroneImg_8.ImageSource = 'drone.png';
            app.ThreatsDetectedEditFieldLabel = uilabel(app.BattlfieldTab);
            app.ThreatsDetectedEditFieldLabel.Visible = 'off';
            app.ThreatsDetectedEditFieldLabel.Text = 'Threats Detected:';
            app.ThreatsDetectedEditField = uieditfield(app.BattlfieldTab, 'numeric');
            app.ThreatsDetectedEditField.Visible = 'off';
            app.DroneImg_3 = uiimage(app.BattlfieldTab);
            app.DroneImg_3.Visible = 'off';
            app.DroneImg_3.ImageSource = 'drone.png';
            app.DroneImg_4 = uiimage(app.BattlfieldTab);
            app.DroneImg_4.Visible = 'off';
            app.DroneImg_4.ImageSource = 'drone.png';
            app.DroneImg_5 = uiimage(app.BattlfieldTab);
            app.DroneImg_5.Visible = 'off';
            app.DroneImg_5.ImageSource = 'drone.png';
            app.DroneImg_6 = uiimage(app.BattlfieldTab);
            app.DroneImg_6.Visible = 'off';
            app.DroneImg_6.ImageSource = 'drone.png';
            app.DroneImg_7 = uiimage(app.BattlfieldTab);
            app.DroneImg_7.Visible = 'off';
            app.DroneImg_7.ImageSource = 'drone.png';
            app.Image = uiimage(app.BattlfieldTab);
            app.Image.ImageSource = 'battlefield_legend.png';
            app.DroneImg = uiimage(app.BattlfieldTab);
            app.DroneImg.Visible = 'off';
            app.DroneImg.ImageSource = 'drone.png';
            app.DroneImg_2 = uiimage(app.BattlfieldTab);
            app.DroneImg_2.Visible = 'off';
            app.DroneImg_2.ImageSource = 'drone.png';
            app.ThreatDetectedBtn = uibutton(app.BattlfieldTab, 'push');
            app.ThreatDetectedBtn.ButtonPushedFcn = createCallbackFcn(app, @ThreatDetectedBtnPushed, true);
            app.ThreatDetectedBtn.Visible = 'off';
            app.ThreatDetectedBtn.Text = '+';
            % Show the figure after all components are created
            app.EWGameofDronesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EWGODs_Main_Control_Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EWGameofDronesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EWGameofDronesUIFigure)
        end
    end
end